classdef MAE457_Project_G10_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotSimTool_G10                matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        InitializationTab               matlab.ui.container.Tab
        SetupTabGroup                   matlab.ui.container.TabGroup
        InitialConditionsTab            matlab.ui.container.Tab
        InitialParaTable2               matlab.ui.control.Table
        SavePara                        matlab.ui.control.Button
        InitialParaTable1               matlab.ui.control.Table
        InstructionLabel2               matlab.ui.control.Label
        InstructionLabel3               matlab.ui.control.Label
        InstructionLabel1               matlab.ui.control.Label
        NumberofLinksEditFieldLabel     matlab.ui.control.Label
        NLinksEditField                 matlab.ui.control.NumericEditField
        XDropDownLabel                  matlab.ui.control.Label
        DoFX                            matlab.ui.control.DropDown
        YLabel                          matlab.ui.control.Label
        DoFY                            matlab.ui.control.DropDown
        ZLabel                          matlab.ui.control.Label
        DoFZ                            matlab.ui.control.DropDown
        LinearDegreesofFreedomLabel     matlab.ui.control.Label
        DHParametersTab                 matlab.ui.container.Tab
        DHParaTabGroup                  matlab.ui.container.TabGroup
        KnownDHParametersTab            matlab.ui.container.Tab
        DHTable1                        matlab.ui.control.Table
        SaveDHPara1                     matlab.ui.control.Button
        Instruction4                    matlab.ui.control.Label
        FindDHParametersTab             matlab.ui.container.Tab
        FindDHTable                     matlab.ui.control.Table
        DHTable2                        matlab.ui.control.Table
        FindDHPara                      matlab.ui.control.Button
        SaveDHPara2                     matlab.ui.control.Button
        DynamicsTab                     matlab.ui.container.Tab
        InitialJointParametersLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        DynamicsSimulationTab           matlab.ui.container.Tab
        RunDynamicsButton               matlab.ui.control.Button
        SimulationTimesecEditFieldLabel  matlab.ui.control.Label
        DySimTime                       matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ForwardDynamics                 matlab.ui.control.RadioButton
        InverseDynamics                 matlab.ui.control.RadioButton
        JointParaTable                  matlab.ui.control.Table
        TauTable                        matlab.ui.control.Table
        DesiredRobotConfigurationLabel  matlab.ui.control.Label
        qTable                          matlab.ui.control.Table
        DyPlotTabGroup                  matlab.ui.container.TabGroup
        JPosPlotTab                     matlab.ui.container.Tab
        JPosPlot                        matlab.ui.control.UIAxes
        JVelPlotTab                     matlab.ui.container.Tab
        JVelPlot                        matlab.ui.control.UIAxes
        TorqueTab                       matlab.ui.container.Tab
        TorqPlot                        matlab.ui.control.UIAxes
        FrictionDropDownLabel           matlab.ui.control.Label
        FrictionDropDown                matlab.ui.control.DropDown
        SymbolicDynamicsEquationTab     matlab.ui.container.Tab
        FormulateSymbolicEquationsofMotionButton  matlab.ui.control.Button
        DySymDisplay                    matlab.ui.control.TextArea
        InstructionLabel1_2             matlab.ui.control.Label
        AxisofGravityDropDownLabel      matlab.ui.control.Label
        GravityDropDown                 matlab.ui.control.DropDown
        ControlsTab                     matlab.ui.container.Tab
        RunControlsButton               matlab.ui.control.Button
        SimulationTimesecEditFieldLabel_2  matlab.ui.control.Label
        SimTime                         matlab.ui.control.NumericEditField
        ControlsInputTable              matlab.ui.control.Table
        SimulationTimeStepLabel         matlab.ui.control.Label
        SimTimeStep                     matlab.ui.control.NumericEditField
        GainTable                       matlab.ui.control.Table
        SimulationControlAlgorithmSwitchLabel  matlab.ui.control.Label
        ControllerSwitch                matlab.ui.control.Switch
        ControlPlot                     matlab.ui.control.UIAxes
        SerialRoboticArmSimulationToolLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Links   % Ordered List of Links
        JType   % Type of Joint (0 for Prismatic and 1 for Revolute)
        l_L     % Length of Link
        l_com   % Positions of Center of mass of Links
        l_comV  % Position Vector of Center of Mass in Link Frame
        JLim    % Limits of joint (units for prismatic and rad for Revolute)
        DH      % DH Parameters
        mass       % Mass of Link
        I_L     % Inertia of Links
        J_m     % Inertia of Motors
        Kr      % Gear Ratio of Motors
        JVar    % Joint Variables
        Fv       % Joint Friction Coefficient (diag)
        FvV     % Joint Friction Coefficients Vector
    end
    
    properties (Access = public)
        NLinks  % Number of Links of Robot
        R       % PC Robot Configuration
        mask    % Mask Vector designating available DoF for Robot
        DoF     % Number of Linear DoF
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Start Up Function to set Robot specifications Directly for
        % Controls Input Table
        Pos0Col                         = zeros(3,1);
        PosDesCol                       = zeros(3,1);
        tabdatCIn                       = table(Pos0Col,PosDesCol);
        app.ControlsInputTable.Data     = tabdatCIn;
        app.ControlsInputTable.ColumnEditable = true;

        % Set Initial Visibility of Dynamics Table
        if app.ButtonGroup.SelectedObject == app.ForwardDynamics
            app.TauTable.Visible    = 'on';
            app.qTable.Visible      = 'off';
        else
            app.TauTable.Visible    = 'off';
            app.qTable.Visible      = 'on';
        end
        end

        % Value changed function: NLinksEditField
        function InputNLinksValueChanged(app, event)
            value                = app.NLinksEditField.Value;
            
            %Create tables to allow user to edit link parameters
            app.NLinks           = value; % Save number of links of robot
            assignin("base",'NLinks',app.NLinks)
            
            % Set Up InitialParaTable1 Rows
            LinksCol             = (1:app.NLinks)';
            JTypeCol             = zeros(app.NLinks,1);
            l_LCol               = zeros(app.NLinks,1);
            l_comCol             = zeros(app.NLinks,1);
            JLimCol              = zeros(app.NLinks,1);
            tabdat1              = table(LinksCol,JTypeCol,l_LCol,l_comCol,JLimCol);
            % Set Joint Type as either Pris or Rev
            catnames             = {'Prismatic';'Revolute'}; % Name Joint Types
            tabdat1.JTypeCol     = categorical(tabdat1.JTypeCol,[0 1],catnames); % Set Column data to categorical data type
            app.InitialParaTable1.Data = tabdat1; % Insert rows into InitialParaTable1
            app.InitialParaTable1.ColumnEditable = [false true true true true]; %Allow for Data to be edited by user
            
            % Set Up InitialParaTable2 Rows
            m_LCol               = zeros(app.NLinks,1); % Zero Columns for Table
            I_LCol               = zeros(app.NLinks,1);
            m_mCol               = zeros(app.NLinks,1);
            I_mCol               = zeros(app.NLinks,1);
            KrCol                = zeros(app.NLinks,1);
            tabdat2              = table(LinksCol,m_LCol,I_LCol,m_mCol,I_mCol,KrCol);
            app.InitialParaTable2.Data = tabdat2; %Insert rows into table
            app.InitialParaTable2.ColumnEditable = [false true true true true true true]; %Allow for Data to be edited by user
            
            % Create Tables for DH Parameters
            a_iCol               = zeros(app.NLinks,1); % Zero Columns for Table
            alpha_iCol           = zeros(app.NLinks,1);
            d_iCol               = zeros(app.NLinks,1);
            theta_iCol           = zeros(app.NLinks,1);
            % Known & Unknown DH Tables
            DHtabdat             = table(LinksCol,JTypeCol,a_iCol,alpha_iCol,d_iCol,theta_iCol);
            DHtabdat.JTypeCol    = categorical(DHtabdat.JTypeCol,[0 1],catnames);
            app.DHTable1.Data    = DHtabdat; % Known Table
            app.DHTable2.Data    = DHtabdat; %Unknown Table
            app.DHTable1.ColumnEditable = [false false true true true true]; % Allow table to be edited by user
            
            % Find DH Table
            JNumColF             = [num2cell(LinksCol);{'End Effector'}]; % Create list of Each Joint
            JTypeColF            = [JTypeCol;0];
            l_LColF              = [l_LCol;0];
            ZDirColF             = zeros(app.NLinks+1,1);
            AlgnYNColF           = zeros(app.NLinks+1,1);
            dhfdat               = table(JNumColF,JTypeColF,l_LColF,ZDirColF,AlgnYNColF);
            catnames2            = {'No';'Yes'};
            catnames3            = {'Z';'Y';'-Z';'-Y'}; % Name Z Direction Change
            dhfdat.JTypeColF     = categorical(dhfdat.JTypeColF,[0 1],catnames);
            dhfdat.ZDirColF      = categorical(dhfdat.ZDirColF,[0 1 2 3],catnames3);
            dhfdat.AlgnYNColF    = categorical(dhfdat.AlgnYNColF,[0 1],catnames2);
            app.FindDHTable.Data = dhfdat;
            app.FindDHTable.ColumnEditable = [false false false true true]; %Allow table to be edited by user
            
            
            % Create Tables for Dynamics
            % Joint Parameters Table
            q0Col                         = zeros(app.NLinks,1);
            qd0Col                        = zeros(app.NLinks,1);
            tabdatJP                      = table(q0Col,qd0Col);
            app.JointParaTable.Data       = tabdatJP; %Insert rows into table
            app.JointParaTable.ColumnEditable   = true; %Allow for Data to be edited by user
            % Tau Table
            TauCol                        = zeros(app.NLinks,1);
            tabdatTau                     = table(TauCol);
            app.TauTable.Data             = tabdatTau; %Insert rows into table
            app.TauTable.ColumnEditable   = true; %Allow for Data to be edited by user
            % q Table
            qCol                           = zeros(app.NLinks,1);
            qTypeCol                       = zeros(app.NLinks,1);
            tabdatq                        = table(qCol,qTypeCol);
            catnames4                      = {'d_i','Theta_i'};
            tabdatq.qTypeCol               = categorical(tabdatq.qTypeCol,[0 1],catnames4);
            app.qTable.Data                = tabdatq; %Insert rows into table
            app.qTable.ColumnEditable      = true; %Allow for Data to be edited by user
            
            % Controller Gain Table
            KpCol                        = 10*ones(3,1);
            KdCol                        = 100*ones(3,1);
            tabdatGain                   = table(KpCol,KdCol);
            app.GainTable.Data           = tabdatGain;
            app.GainTable.ColumnEditable = [true true];
            
        end

        % Button pushed function: SavePara
        function SaveParaPushed(app, event)
            % Button to save the given link parameters to be used as
            % variables in other parts of program.
            app.Links   = table2array(app.InitialParaTable1.Data(:,1));
            assignin('base','NLinks',app.NLinks)
            JType1      = table2array(app.InitialParaTable1.Data(:,2));
            app.JType   = zeros(app.NLinks,1);
            app.JVar    = zeros(app.NLinks,1);
            app.l_L     = table2array(app.InitialParaTable1.Data(:,3));
            app.l_com   = table2array(app.InitialParaTable1.Data(:,4));
            app.JLim    = table2array(app.InitialParaTable1.Data(:,5));
            m_l         = table2array(app.InitialParaTable2.Data(:,2));
            app.I_L     = table2array(app.InitialParaTable2.Data(:,3));
            m_m         = table2array(app.InitialParaTable2.Data(:,4));
            app.J_m     = table2array(app.InitialParaTable2.Data(:,5));
            app.Kr      = table2array(app.InitialParaTable2.Data(:,6));
            
            % Calculate Friction Coeffients
            app.Fv      = diag(app.Kr)*diag(app.J_m);
            app.FvV     = app.Fv*ones(1,app.NLinks)';
            
            % Calculate Joint Mass including motor
            app.mass = zeros(app.NLinks,1);
            for i = 1:app.NLinks-1
                if i ~= app.NLinks
                    app.mass(i) = m_l(i) + m_m(i+1);
                else
                    app.mass(i) = m_l(i);
                end
            end
    
            % Assign binary values to Joint Type for tracking
            for i = 1:app.NLinks
                if any(JType1(i) == 'Prismatic')
                    app.JType(i)    = 0;
                else
                    app.JType(i)    = 1;
                    % Limits from Degrees to Radians for Revolute Joints
                    app.JLim(i)     = deg2rad(app.JLim(i));
                end
            end
            app.JVar    = categorical(app.JType,[0,1],{'d_i' 'Theta_i'});
            
            
            % Save Degree of Freedom ask a mask matrix. All Rotational
            % components are zero to ignore orientation
            app.mask = [str2double(app.DoFX.Value), str2double(app.DoFY.Value), str2double(app.DoFZ.Value),0, 0, 0];
            app.DoF     = sum(app.mask);
            
            % Send Relavent Link Data to DH Tables and Dynamics Tables
            app.DHTable1.Data(:,2)               = app.InitialParaTable1.Data(:,2);
            app.FindDHTable.Data(1:app.NLinks,2) = app.InitialParaTable1.Data(:,2);
            app.FindDHTable.Data(1:app.NLinks,3) = app.InitialParaTable1.Data(:,3);
            app.DHTable2.Data(:,2)               = app.InitialParaTable1.Data(:,2);
            app.qTable.Data(:,2)                 = table(app.JVar);
            
            assignin('base','JLim',app.JLim)
            assignin('base','JType',app.JType)
            assignin('base','a_link',app.l_L)
            assignin('base','mask',app.mask);
            assignin('base','DoF',app.DoF);
            assignin("base",'Fv',app.Fv)
        end

        % Button pushed function: SaveDHPara1
        function SaveDHPara1ButtonPushed(app, event)
            % Button to save the given DH parameters to be used as
            % variables in other parts of program.
            a_DH        = table2array(app.DHTable1.Data(:,3));
            alpha_DH    = table2array(app.DHTable1.Data(:,4));
            alpha_DH    = deg2rad(alpha_DH);
            d_DH        = table2array(app.DHTable1.Data(:,5));
            theta_DH    = table2array(app.DHTable1.Data(:,6));
            theta_DH    = deg2rad(theta_DH);
            app.DH      = [a_DH,alpha_DH,d_DH,theta_DH];
            
            % Track Joint Variables with 0.0001 values
            for i = 1:app.NLinks
                if app.JType(i) == 0 % Prismatic Condition
                    app.DH(i,3)             = 0.0001;
                    app.DHTable1.Data(i,5)  = array2table(app.DH(i,3));
                else % Revolute Condition
                    app.DH(i,4)             = 0.0001;
                    app.DHTable1.Data(i,6)  = array2table(app.DH(i,4));
                end
            end
            
            
            % Find Center of Mass Unit Vector in Link Frame
            assignin('base','l_com',app.l_com)
            for i = 1:app.NLinks
                if app.JType(i) == 0 && app.DH(i,1) == 0
                        app.l_comV(:,i) = (app.l_com(i)').*[0 0 1]';
                else
                        app.l_comV(:,i) = (app.l_com(i)').*[1 0 0]';
                end
            end
            
            
            % Create Robot Simulation with SerialLink
            dh      = [app.DH(:,4) app.DH(:,3) app.DH(:,1) app.DH(:,2)];
            assignin("base","dh1",dh);
            for i = 1:app.NLinks
                if app.JType(i) == 0 % Prismatic Joint Condition
                    L(i)        = Link('theta',dh(i,1), 'a', dh(i,3), 'alpha', dh(i,4), 'm', app.mass(i), 'r', app.l_comV(:,i), 'I', app.I_L(i).*eye(3), 'G', app.Kr(i),'B',app.FvV(i), 'Jm',app.J_m(i).*eye(3));
                    L(i).qlim   = [0, app.JLim(i)];
                else % Revolute Joint Condition
                    L(i)        = Link('d',dh(i,2), 'a', dh(i,3), 'alpha', dh(i,4), 'm', app.mass(i), 'r', app.l_comV(:,i), 'I', app.I_L(i).*eye(3), 'G', app.Kr(i),'B',app.FvV(i), 'Jm',app.J_m(i).*eye(3));
                    L(i).qlim   = [-app.JLim(i),app.JLim(i)];
                end
            end
            app.R = SerialLink(L);
            app.R.name = 'Drew Brees';
            assignin("base","R",app.R)
            
            % Plot Robot
            RobotPlot

        end

        % Button pushed function: FindDHPara
        function FindDHParaButtonPushed(app, event)
            % Button to Find DH Parameters from Data in Find Table
            
            % Initialize Variables for Loop
            JNum        = [1:app.NLinks,0]'; % Track End Effector as zero Link
            JTypeF      = [app.JType;0]; % Track End Effector Joint as Pris
            l_LF        = [app.l_L;0]; % Track End Effector Joint Length as zero
            ZDirTab     = table2array(app.FindDHTable.Data(:,4)); % Save Z Axis Data from DHFindTable
            ZDir        = zeros(length(JNum),1);
            AlgYNTab    = table2array(app.FindDHTable.Data(:,5)); %Save Allignment Data from DHFindTable
            AlgYN       = zeros(length(JNum),1);
            
            % Initialize DH Variables
            a_DH        = zeros(app.NLinks,1);
            alpha_DH    = zeros(app.NLinks,1);
            d_DH        = zeros(app.NLinks,1);
            theta_DH    = zeros(app.NLinks,1);
            
            % Assign numeric values to Joint  for tracking
            for i = 1:length(JNum)
                if any(ZDirTab(i) == 'Z')
                    ZDir(i)    = 1;
                elseif any(ZDirTab(i) == 'Y')
                    ZDir(i)    = 2;
                elseif any(ZDirTab(i) == '-Z')
                    ZDir(i)    = 3;
                else % -Y Case
                    ZDir(i)    = 4;
                end
            end
            ZDir(1) = 1; % Ensures first joint is set at origin.
            
            % Assign numeric values to Joint Allignment for Tracking
            for i = 1:length(JNum)-1
                if any(AlgYNTab(i) == 'No')
                    AlgYN(i)    = 0;
                    app.l_comV(:,i) = [app.l_com(i); 0; 0];
                else
                    AlgYN(i)    = 1;
                    app.l_comV(:,i) = [0; 0; app.l_com(i)];
                end
            end
            
           % Determine DH Parameters from Find Table
            for i = 1:app.NLinks
                if JTypeF(i) == 0 % Prismatic Link Condition
                    a_DH(i)     = 0;
                    d_DH(i)     = 0.0001;
                    theta_DH(i) = 0;
                    if ZDir(i+1) == 1 % Z Case
                            alpha_DH(i) = 0;
                        elseif ZDir(i+1) == 2 % Y Case
                            alpha_DH(i) = -90;
                        elseif ZDir(i+1) == 3 % -Z Case
                            alpha_DH(i) = 0;
                            d_DH(i) = -d_DH(i);
                        else % -Y Case
                            alpha_DH(i) = 90;
                    end
                else % Revolute Link Condition
                    if AlgYN(i) == 0 % Rot Axis Normal to Link Condition
                        a_DH(i) = l_LF(i);
                        d_DH(i) = 0;
                        theta_DH(i) = 0.0001;
                        if ZDir(i+1) == 1 % Z Case
                            alpha_DH(i) = 0;
                        elseif ZDir(i+1) == 2 % Y Case
                            alpha_DH(i) = -90;
                        elseif ZDir(i+1) == 3 % -Z Case
                            alpha_DH(i) = 0;
                            d_DH(i) = -d_DH(i);
                        else % -Y Case
                            alpha_DH(i) = 90;
                        end
                    else % Rot Axis Parallel with Link Condition
                        if i == 1 % First Link Case
                            a_DH(i) = 0;
                            d_DH(i) = 0;
                            theta_DH(i) = 0.0001;
                        else % All other cases
                            a_DH(i) = 0;
                            d_DH(i) = l_LF(i);
                            theta_DH(i) = 0.0001;
                        end
                        if ZDir(i+1) == 1 % Z Case
                            alpha_DH(i) = 0;
                        elseif ZDir(i+1) == 2 % Y Case
                            alpha_DH(i) = -90;
                        elseif ZDir(i+1) == 3 % -Z Case
                            alpha_DH(i) = 0;
                            d_DH(i) = -d_DH(i);
                        else % -Y Case
                            alpha_DH(i) = 90;
                        end
                    end 
                end
            end
            
            % Add DH Parameters to DHTable2
            app.DH = [a_DH,alpha_DH,d_DH,theta_DH];
            app.DHTable2.Data(:,3) = array2table(app.DH(:,1));
            app.DHTable2.Data(:,4) = array2table(app.DH(:,2));
            app.DHTable2.Data(:,5) = array2table(app.DH(:,3));
            app.DHTable2.Data(:,6) = array2table(app.DH(:,4));
            
            % Convert alpha and theta to degrees
            app.DH(:,2) = rad2deg(app.DH(:,2));
            for i = 1:length(app.DH(:,4)) % Change to radians only if not a Joint Variable
                if app.DH(i,4) ~= 0.0001 || app.DH(i,4) ~= -0.0001
                    app.DH(:,4) = rad2deg(app.DH(:,4));
                else
                end
            end
            
        end

        % Button pushed function: SaveDHPara2
        function SaveDHPara2ButtonPushed(app, event)
            % Button to save the given DH parameters to be used as
            % variables in other parts of program.
            a_DH        = table2array(app.DHTable2.Data(:,3));
            alpha_DH    = table2array(app.DHTable2.Data(:,4));
            alpha_DH    = deg2rad(alpha_DH);
            d_DH        = table2array(app.DHTable2.Data(:,5));
            theta_DH    = table2array(app.DHTable2.Data(:,6));
            theta_DH    = deg2rad(theta_DH);
            app.DH      = [a_DH,alpha_DH,d_DH,theta_DH];
            
            % Track Variable Joint Parameters with 0.0001 values
            for i = 1:app.NLinks
                if app.JType(i) == 0
                    app.DH(i,3)             = 0.0001;
                    app.DHTable2.Data(i,3)  = 0.0001;
                else
                    app.DH(i,4)             = 0.0001;
                    app.DHTable2.Data(i,4)  = 0.0001;
                end
            end
            
            % Create Robot Simulation with SerialLink
            dh      = [app.DH(:,4) app.DH(:,3) app.DH(:,1) app.DH(:,2)];
            assignin("base","dh1",dh);
            for i = 1:app.NLinks
                if app.JType(i) == 0 % Prismatic Joint Condition
                    L(i)        = Link('theta',dh(i,1), 'a', dh(i,3), 'alpha', dh(i,4), 'm', app.mass(i), 'r', app.l_comV(:,i), 'I', app.I_L(i).*eye(3), 'G', app.Kr(i),'B',app.FvV(i), 'Jm',app.J_m(i).*eye(3));
                    L(i).qlim   = [0, app.JLim(i)];
                else % Revolute Joint Condition
                    L(i)        = Link('d',dh(i,2), 'a', dh(i,3), 'alpha', dh(i,4), 'm', app.mass(i), 'r', app.l_comV(:,i), 'I', app.I_L(i).*eye(3), 'G', app.Kr(i),'B',app.FvV(i), 'Jm',app.J_m(i).*eye(3));
                    L(i).qlim   = [-app.JLim(i),app.JLim(i)];
                end
            end
            app.R = SerialLink(L);
            assignin("base",'DH',app.DH);
            assignin("base","R",app.R)
            
            % Plot Robot
            RobotPlot
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if selectedButton == app.ForwardDynamics
                app.qTable.Visible      = 'off';
                app.TauTable.Visible    = 'on';
            else
                app.qTable.Visible      = 'on';
                app.TauTable.Visible    = 'off';
            end
            
        end

        % Button pushed function: RunDynamicsButton
        function RunDynamicsButtonPushed(app, event)
            % Use Initial Contions to Run Dynamics Problem and get
            % equations of motion
            % Extract Data from Tables
            Rob                 = app.R;
            q0                  = table2array(app.JointParaTable.Data(:,1));
            qd0                 = table2array(app.JointParaTable.Data(:,2));
            Time                = app.DySimTime.Value;
            T                   = 0:0.05:Time;
            Tau_desired         = zeros(app.NLinks,1);
            q_desired           = zeros(app.NLinks,1);
            if app.ButtonGroup.SelectedObject == app.ForwardDynamics
                Tau_desired     = table2array(app.TauTable.Data(:,1));
                assignin('base','T_des',Tau_desired)
            else
                q_desired       = table2array(app.qTable.Data(:,1));
            end
            
            % Convert Revolute Joint Parameters to Radians
            for i = 1:app.NLinks
                if app.JType == 1
                    q0(i)    = deg2rad(q0(i));
                    qd0(i)   = deg2rad(qd0(i));
                    q_desired(i) = deg2rad(q_desired(i));
                    assignin("base","q0",q0);
                    assignin("base","qd0",qd0); 
                    assignin('base','q_des',q_desired)
                end
            end
            
            % Turn off friction if switch is selected.
            if strcmp(app.FrictionDropDown.Value,'No')
                Rob = Rob.nofriction('all');
            else
            end
                   
            if app.ButtonGroup.SelectedObject == app.ForwardDynamics
                Torqfun         = @(t,q,qd,a) Tau_desired';
                [time,q,qd]     = Rob.fdyn(Time,Torqfun,q0',qd0');
                assignin('base','q_dy',q)
                assignin('base','qd_dy',qd)
                assignin('base','time_dy',time)
                leg = cell(1,app.NLinks);
                for i = 1:app.NLinks
                    plot(app.JPosPlot,time,q(:,i),'DisplayName',strcat('Joint ',string(i)))
                    leg{i} = strcat('Joint',num2str(i));
                    hold(app.JPosPlot,'on')
                end
                title(app.JPosPlot,'Joint Position vs Time','Interpreter','latex')
                xlabel(app.JPosPlot,'Time (sec)','Interpreter',"latex")
                ylabel(app.JPosPlot,'Position (m or rad)','Interpreter',"latex")
                legend(app.JPosPlot,leg,'Location','best')
                hold(app.JPosPlot,"off")
                app.DyPlotTabGroup.SelectedTab = app.JPosPlotTab;
                
                for i = 1:app.NLinks
                    plot(app.JVelPlot,time,qd(:,i),'DisplayName',strcat('Joint ',string(i)))
                    hold(app.JVelPlot,'on')
                end
                title(app.JVelPlot,'Joint Velocity vs Time','Interpreter','latex')
                xlabel(app.JVelPlot,'Time (sec)','Interpreter',"latex")
                ylabel(app.JVelPlot,'Velocity ($\frac{m}{sec}$ or $\frac{rad}{sec}$)','Interpreter',"latex")
                legend(app.JVelPlot,leg,'Location','best')
                hold(app.JVelPlot,"off")
                
            else
              [q_traj,qd_traj,qdd_traj] = jtraj(q0',q_desired',T');
              Tau_Result    = app.R.rne(q_traj,qd_traj,qdd_traj);
              assignin('base','TauR',Tau_Result)
              leg = cell(1,app.NLinks);
              for i = 1:app.NLinks
                    plot(app.TorqPlot,T,Tau_Result(:,i))
                    leg{i} = strcat('Joint',num2str(i));
                    hold(app.TorqPlot,'on')
              end
                title(app.TorqPlot,'Joint Torque from q0 to qf','Interpreter','latex')
                xlabel(app.TorqPlot,'Time (sec)','Interpreter',"latex")
                ylabel(app.TorqPlot,'Joint Torque (Nm or $\frac{J}{rad}$)','Interpreter',"latex")
                legend(app.TorqPlot,leg,'Location','best')
                hold(app.TorqPlot,"off")
                app.DyPlotTabGroup.SelectedTab = app.TorqueTab;
            end
            
        end

        % Button pushed function: RunControlsButton
        function RunControlsButtonPushed(app, event)
            % Collect Simulation Values from Controls Tab
            Time_sim    = app.SimTime.Value;
            T_step      = app.SimTimeStep.Value;
            x0          = table2array(app.ControlsInputTable.Data(:,1));
            xf          = table2array(app.ControlsInputTable.Data(:,2));
            Kp          = table2array(app.GainTable.Data(:,1));
            Kp          = diag(Kp);
            Kd          = table2array(app.GainTable.Data(:,2));
            Kd          = diag(Kd);
            Rob         = app.R;
            assignin('base','Kp', Kp)
            assignin('base','Kd', Kd)
            
            % Create Time matrix
            Time = (0:T_step:Time_sim);
            
            % Determine Initial Joint Positions
            T0                      = transl(x0);                        % Transformation Matrix of x0
            q0i                     = Rob.ikunc(T0);  % Inverse Kinematics to find joint positions q0 initial guess
            q0                      = Rob.ikine(T0,q0i,app.mask); % Inverse Kinematics w/ mask for underactuated robot
            
            % Determine End Effector Trajectory
            % trapezoidal velocity profile trajectory for path coordinate from 0 to 1
            dx_max                  = 1.5;                % maximum velocity
            x_seg                   = norm(xf-x0);        % Segment Length
            t_f                     = 1;                  % final time
            [T_1,s,ds,dds,~]      = trapez(0,1,dx_max/x_seg,t_f,T_step);
            n                       = length(Time);
            m                       = size(T_1,1);
            x_path                  = zeros(n,3);
            xd_path                 = x_path;
            xdd_path                = x_path;
            x_path(1:m,:)           = ones(m,1)*x0' + s*(xf - x0)';
            x_path(m+1:n,:)         = ones(n-m,1)*xf';
            xd_path(1:m,:)          = ds*(xf - x0)';
            xdd_path(1:m,:)         = dds*(xf - x0)';
            
            % Check for singularities and avoid can only do so for
            % redundant manipulators
            if app.NLinks > 3
            single = 0;
            for i = 1:length(x_path)
                Tran = transl(x_path(i,:));
                q_check = Rob.ikunc(Tran);
                m   = Rob.maniplty(q_check,'T'); % Manipubility, low near singluarities.
                if m < 1
                    qs = Rob.qmincon(q_check);
                    qn = qs;
                    x_path(i,:) = Rob.fkine(qn);
                    single = single + 1;
                elseif single > 30
                    disp('Out of workspace. Unreachable')
                    return
                else
                    
                end
            end
            else
            end
            
            
            x_d                 = x_path;
            xd_d                = xd_path;
            xdd_d               = xdd_path;

            
            % Assign Variables used in Sim to base workspace
            assignin('base','x0',x0)
            assignin('base','xf',xf)
            assignin('base','Kp',Kp)
            assignin('base','Kd',Kd)
            assignin('base','R',Rob)
            assignin("base","T",Time')
            assignin("base","T_step",T_step)
            assignin('base','x_d',x_d)
            assignin('base','xd_d',xd_d)
            assignin('base','xdd_d',xdd_d)
            assignin('base','q0',q0)
            
            % Run chosen Controls algorithm in simulink
            if strcmp(app.ControllerSwitch.Value,'PD wth Gravity Comp')
                sim_PD_Control_wGC
            else
                sim_InvJacControl
            end
            
        end

        % Button pushed function: 
        % FormulateSymbolicEquationsofMotionButton
        function SymEoMButtonPushed(app, event)
            % Define Symbolic variables for Parameters
            syms q1 q2 q3 q4 q5 q6 q7 real
            syms qd1 qd2 qd3 qd4 qd5 qd6 qd7 real
            syms qdd1 qdd2 qdd3 qdd4 qdd5 qdd6 qdd7 real
            syms Tau1 Tau2 Tau3 Tau4 Tau5 Tau6 Tau7 real
            syms ml1 ml2 mm1 mm2 Im1 Im2 Il1 Il2 I1 I2 a1 a2 l1 l2 g real
            Var=[q1 q2 q3 q4 q5 q6 q7];
            q_cache = [q1 q2 q3 q4 q5 q6 q7];
            q_dots_cache = [qd1,qd2,qd3,qd4,qd5,qd6,qd7];
            q_ddots_cache = [qdd1,qdd2,qdd3,qdd4,qdd5,qdd6,qdd7];
            Tau_c = [Tau1 Tau2 Tau3 Tau4 Tau5 Tau6 Tau7];
            
            % Create matrix for used joint variables
            qs = q_dots_cache(1:app.NLinks)';
            qds = q_dots_cache(1:app.NLinks)';
            qdds = q_dots_cache(1:app.NLinks)';
            Taus = Tau_c(1:app.NLinks)';
            
            % Define input parameters from robot intialization tab
            Imi=app.J_m;
            Ili=app.I_L;
            COG=app.l_com;
            n=app.NLinks;
            kr=app.Kr;
            mmi=table2array(app.InitialParaTable2.Data(:,4));
            mli=table2array(app.InitialParaTable2.Data(:,2));
            a= table2array(app.DHTable1.Data(:,3));
            alpha = table2array(app.DHTable1.Data(:,4));
            theta_dh= table2array(app.DHTable1.Data(:,6));
            
            % Determine direction of gravity from drop menu
            if strcmp(app.GravityDropDown.Value,'X')
                g0 = [-g 0 0]';
            elseif strcmp(app.GravityDropDown.Value,'Y')
                g0 = [0 -g 0]';
            else
                g0 = [0 0 -g]';
            end
            
            % Tranformation of link
            I=eye(4);
            T=[]; 
            
            for i=1:n
                if app.JType(i)==1
                    q=Var(i);
                    di=0;
                    A =[ cos(q)  -sin(q)*cosd(alpha(i))    sin(q)*sind(alpha(i))    a(i)*cos(q); 
                         sin(q)   cos(q)*cosd(alpha(i))    -cos(q)*sind(alpha(i))   a(i)*sin(q);
                         0        sind(alpha(i))           cosd(alpha(i))           di ;
                         0        0                        0                         1];
                
                else
                    q=theta_dh(i);
                    di=Var(i);
                    A =[ cosd(q)  -sind(q)*cosd(alpha(i))    sind(q)*sind(alpha(i))    a(i)*cosd(q); 
                         sind(q)   cosd(q)*cosd(alpha(i))    -cosd(q)*sind(alpha(i))   a(i)*sind(q);
                         0        sind(alpha(i))             cosd(alpha(i))               di ;
                         0        0                          0                            1];
             
                end
            I=I*A;
            T=[T I];  
            end
            T0=[1 0 0 0 T(1,:);0 1 0 0 T(2,:); 0 0 1 0 T(3,:); 0 0 0 1 T(4,:)];
            
            % Tranformation of COG
            IP=eye(4);
            TP=[];
            for i=1:n
                if app.JType(i)==1
                    q=Var(i);
                    di=0;
                else
                    q=deg2rad(theta_dh(i));
                    di=Var(i);
                end
                % Exctracting the previous link T 
            Ini=[T0(:,4*i-3) T0(:,4*i-2) T0(:,4*i-1) T0(:,4*i)];
                AP =[ cos(q)  -sin(q)*cosd(alpha(i))    sin(q)*sind(alpha(i))    COG(i)*cos(q); 
                    sin(q)   cos(q)*cosd(alpha(i))    -cos(q)*sind(alpha(i))   COG(i)*sin(q);
                    0        sind(alpha(i))           cosd(alpha(i))           di ;
                    0        0                        0                      1];
                % Multipling previous link 1 and cog of link 2 to get T for cog
                IP=Ini*AP;
                TP=[TP IP];
            end
            
            % Collecting the values z,p and p_cog from transfomation matrix
            z=[];
            p=[];
            p_COG=[];
                for k=0:n-1
                    z_i=[T(1,3+k*4) ;T(2,3+k*4) ;T(3,3+k*4)];
                    p_i=[T(1,4+k*4); T(2,4+k*4); T(3,4+k*4)];
                     P_COG=[TP(1,4+k*4); TP(2,4+k*4); TP(3,4+k*4)];
                    z=[z z_i];
                    p=[p p_i];
                    p_COG=[p_COG P_COG];
                end
                
            % adding a intial cordinate [0 0 0] to p
                P0=[0 p(1,:);0 p(2,:); 0 p(3,:)];
                
            % zmi=z(i-1)
                zm=[];
            for v=1:n-1
                x0=z(:,v);
               zm=[zm x0];
            end
            
            % pmi=p(i-1)
            pm=[];
            for u=1:n-1
                x=p(:,u);
               pm=[pm x];
            end
            Pm=[0 pm(1,:);0 pm(2,:); 0 pm(3,:)];
            
            % Calculating the jacobian
                Jpli=[];
                Joli=[];
                Jpmi=[];
                Jomi=[];
            for t=1:n
                % for cal Jpl and Jol
                if app.JType(i)==1
                    for j=2:n+1
                        if j-1>t
                            Jplj=[0;0;0];
                            Jolj=[0;0;0];
                            Joli=[Joli Jolj];
                            Jpli=[Jpli Jplj];
                        else
                            Jplj=cross(z(:,j-1),((p_COG(:,t)-P0(:,j-1))));
                            Jolj=z(:,j-1);
                            Joli=[Joli Jolj];
                            Jpli=[Jpli Jplj];
                        end
                    end
                else
                    for j=2:n+1
                          if j-1>t
                            Jplj=[0;0;0];
                            Jolj=[0;0;0];
                            Jpli=[Jpli Jplj];
                            Joli=[Joli Jolj];
                          else
                            Jplj=z(:,j-1);
                            Jolj=[0;0;0];
                            Jpli=[Jpli Jplj];
                            Joli=[Joli Jolj];
                          end
                    end
                end
            % for cal Jpm
                if app.JType(i)==1
                    for j=2:n+1
                         if j>t
                              Jpmj=[0;0;0];
                              Jpmi=[Jpmi Jpmj];
                         else
                              Jpmj=cross(z(:,j-1),(Pm(:,t)-P0(:,j-1)));
                              Jpmi=[Jpmi Jpmj];
                         end
                    end
                else
                    for j=2:n+1
                        if j>t
                              Jpmj=[0;0;0];
                              Jpmi=[Jpmi Jpmj];
                        else
                              Jpmj=z(:,j-1);
                              Jpmi=[Jpmi Jpmj];
                        end
                    end
                end
                % for cal Jom 
                for j=2:n+1
                         if j-1>t % If j<i
                             Jomj=[0;0;0];
                             Jomi=[Jomi Jomj];
                         else 
                             % checking if i and j are equal
                             if j-1==t % if j=i
                                 Jomj=kr(t)*z(:,t);
                             else % if j>i
                                 Jomj=Jolj;
                             end
                             Jomi=[Jomi Jomj];
                         end
                 end
            end
            
            % separating Jpli values and calculting B matrix
             Jpl = Jpli;
             Jol = Joli;
             Jpm = Jpmi;
             Jom = Jomi;

             %Calculate B and G matrices
             Bq     = zeros(app.NLinks);
             B      = Bq;
             Gq     = zeros(app.NLinks,1);
             G      = Gq;
             ind    = 1;
             for i = 1:app.NLinks
                     Bq = mli(i)*(Jpl(:,ind:i*n)')*Jpl(:,ind:i*n) + Jol(:,ind:i*n)'*Ili(i)*Jol(:,ind:i*n) + mmi(i)*(Jpm(:,ind:i*n)')*Jpm(:,ind:i*n) + (Jom(:,ind:i*n)')*Imi(i)*Jom(:,ind:i*n);
                     B = B + Bq;
                     Gq = mli(i)*(g0')*Jpl(:,ind:i*n) + mmi(i)*(g0')*Jpm(:,ind:i*n);
                     G = G + Gq;
                     ind = ind + n; % Index to increase split Jpl into separate links
             end
             G = sum(Gq);
             
             % Calculate C Matrix
             C = zeros(n);
             for i = 1:app.NLinks
                 for j = 1:app.NLinks
                     for k = 1:app.NLinks
                        Ck = (1/2)*(diff(B(i,j),qs(k)) + diff(B(i,j),qs(k)) - diff(B(j,k),qs(i)));
                        C(i,j) = C(i,j) + Ck*qs(k);
                     end
                 end
             end
             
             % Build Equations of motion from components
             Tau =  B*qdds + C*qds + app.Fv*qds + G;
             Tau = simplify(Tau);
             
             % Write Equations to text box.
             app.DySymDisplay.Value = ''; % Clear Text display if there is writing.
             for i = 1:app.NLinks
                 app.DySymDisplay.Value = [app.DySymDisplay.Value; sprintf('%s = %s', Taus(i), Tau(i))];
             end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotSimTool_G10 = uifigure('Visible', 'off');
            app.RobotSimTool_G10.Name = 'RobotSimTool';
            app.RobotSimTool_G10.Tag = 'MAE547RobotSim';
            app.MainTabGroup = uitabgroup(app.RobotSimTool_G10);
            app.InitializationTab = uitab(app.MainTabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.SetupTabGroup = uitabgroup(app.InitializationTab);
            app.InitialConditionsTab = uitab(app.SetupTabGroup);
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.InitialParaTable2 = uitable(app.InitialConditionsTab);
            app.InitialParaTable2.ColumnName = {'Link Number'; 'Link Mass (m_li)'; 'Link Inertia (I_li)'; 'Motor Mass (m_mi)'; 'Motor Inertia (I_mi)'; 'Motor Gear Ratio (kr_i)'};
            app.InitialParaTable2.RowName = {};
            app.SavePara = uibutton(app.InitialConditionsTab, 'push');
            app.SavePara.ButtonPushedFcn = createCallbackFcn(app, @SaveParaPushed, true);
            app.SavePara.Text = 'Save Parameters';
            app.InitialParaTable1 = uitable(app.InitialConditionsTab);
            app.InitialParaTable1.ColumnName = {'Link Number'; 'Joint Type'; 'Link Length'; 'Link Center of Mass (l_i)'; 'Joint Limit (+/-)'};
            app.InitialParaTable1.RowName = {};
            app.InstructionLabel2 = uilabel(app.InitialConditionsTab);
            app.InstructionLabel2.Text = 'When finished enter DH Parameters in next tab';
            app.InstructionLabel3 = uilabel(app.InitialConditionsTab);
            app.InstructionLabel3.Text = {'If you know the DH Parameters enter them directly in the Known DH Parameters tab.'; 'If DH Parameters are unknown go to the Find DH Parameters tab to calculate the DH'; 'Parameters by inputting the robot''s geometry.'};
            app.InstructionLabel1 = uilabel(app.InitialConditionsTab);
            app.InstructionLabel1.Text = {'Once table is created with appropriate number of links fill in the table with the appropriate information then save for use.'; 'For Joint Limits enter the center position for Revolute Joints in deg and for Prismatic Joints enter the greatest possible extension. '};
            app.NumberofLinksEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.NLinksEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.NLinksEditField.ValueChangedFcn = createCallbackFcn(app, @InputNLinksValueChanged, true);
            app.XDropDownLabel = uilabel(app.InitialConditionsTab);
            app.XDropDownLabel.Text = 'X';
            app.DoFX = uidropdown(app.InitialConditionsTab);
            app.DoFX.Items = {'0', '1'};
            app.DoFX.Value = '1';
            app.YLabel = uilabel(app.InitialConditionsTab);
            app.YLabel.Text = 'Y';
            app.DoFY = uidropdown(app.InitialConditionsTab);
            app.DoFY.Items = {'0', '1'};
            app.DoFY.Value = '1';
            app.ZLabel = uilabel(app.InitialConditionsTab);
            app.ZLabel.Text = 'Z';
            app.DoFZ = uidropdown(app.InitialConditionsTab);
            app.DoFZ.Items = {'0', '1'};
            app.DoFZ.Value = '1';
            app.LinearDegreesofFreedomLabel = uilabel(app.InitialConditionsTab);
            app.LinearDegreesofFreedomLabel.Text = 'Linear Degrees of Freedom';
            app.DHParametersTab = uitab(app.SetupTabGroup);
            app.DHParametersTab.Title = 'DH Parameters';
            app.DHParaTabGroup = uitabgroup(app.DHParametersTab);
            app.KnownDHParametersTab = uitab(app.DHParaTabGroup);
            app.KnownDHParametersTab.Title = 'Known DH Parameters';
            app.DHTable1 = uitable(app.KnownDHParametersTab);
            app.DHTable1.ColumnName = {'Link Number'; 'Link Type'; 'a_i'; 'alpha_i (deg)'; 'd_i'; 'theta_i (deg)'};
            app.DHTable1.RowName = {};
            app.SaveDHPara1 = uibutton(app.KnownDHParametersTab, 'push');
            app.SaveDHPara1.ButtonPushedFcn = createCallbackFcn(app, @SaveDHPara1ButtonPushed, true);
            app.SaveDHPara1.Text = 'Save DH Parameters';
            app.Instruction4 = uilabel(app.KnownDHParametersTab);
            app.Instruction4.Text = {'For Revolute Joints'; 'theta_i will display 0.0001.'; ''; 'For Prismatic Joints '; 'd_i will display 0.0001.'; ''; 'Link Down only used to designate '; 'Prismatic Joints that move '; 'in downwards.'};
            app.FindDHParametersTab = uitab(app.DHParaTabGroup);
            app.FindDHParametersTab.Title = 'Find DH Parameters';
            app.FindDHTable = uitable(app.FindDHParametersTab);
            app.FindDHTable.ColumnName = {'Joint Number'; 'Joint Type'; 'Link Length'; 'Direction of Cosine Z-Axis '; 'Joints Aligned?'};
            app.FindDHTable.RowName = {};
            app.DHTable2 = uitable(app.FindDHParametersTab);
            app.DHTable2.ColumnName = {'Link Number'; 'Link Type'; 'a_i'; 'alpha_i (deg)'; 'd_i'; 'theta_i (deg)'};
            app.DHTable2.RowName = {};
            app.FindDHPara = uibutton(app.FindDHParametersTab, 'push');
            app.FindDHPara.ButtonPushedFcn = createCallbackFcn(app, @FindDHParaButtonPushed, true);
            app.FindDHPara.Text = 'Find DH Parameters';
            app.SaveDHPara2 = uibutton(app.FindDHParametersTab, 'push');
            app.SaveDHPara2.ButtonPushedFcn = createCallbackFcn(app, @SaveDHPara2ButtonPushed, true);
            app.SaveDHPara2.Text = 'Save DH Parameters';
            app.DynamicsTab = uitab(app.MainTabGroup);
            app.DynamicsTab.Title = 'Dynamics';
            app.InitialJointParametersLabel = uilabel(app.DynamicsTab);
            app.InitialJointParametersLabel.Text = 'Initial Joint Parameters';
            app.TabGroup = uitabgroup(app.DynamicsTab);
            app.DynamicsSimulationTab = uitab(app.TabGroup);
            app.DynamicsSimulationTab.Title = 'Dynamics Simulation';
            app.RunDynamicsButton = uibutton(app.DynamicsSimulationTab, 'push');
            app.RunDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @RunDynamicsButtonPushed, true);
            app.RunDynamicsButton.Text = 'Run Dynamics';
            app.SimulationTimesecEditFieldLabel = uilabel(app.DynamicsSimulationTab);
            app.SimulationTimesecEditFieldLabel.Text = 'Simulation Time (sec)';
            app.DySimTime = uieditfield(app.DynamicsSimulationTab, 'numeric');
            app.DySimTime.Value = 5;
            app.ButtonGroup = uibuttongroup(app.DynamicsSimulationTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ForwardDynamics = uiradiobutton(app.ButtonGroup);
            app.ForwardDynamics.Text = 'Forward Dynamics';
            app.ForwardDynamics.Value = true;
            app.InverseDynamics = uiradiobutton(app.ButtonGroup);
            app.InverseDynamics.Text = 'Inverse Dynamics';
            app.JointParaTable = uitable(app.DynamicsSimulationTab);
            app.JointParaTable.ColumnName = {'Initial Joint Pos (q0)'; 'Initial Joint Vel (qdot_0)'};
            app.JointParaTable.RowName = {};
            app.TauTable = uitable(app.DynamicsSimulationTab);
            app.TauTable.ColumnName = {'Joint Torque'};
            app.TauTable.RowName = {};
            app.DesiredRobotConfigurationLabel = uilabel(app.DynamicsSimulationTab);
            app.DesiredRobotConfigurationLabel.Text = 'Desired Robot Configuration';
            app.qTable = uitable(app.DynamicsSimulationTab);
            app.qTable.ColumnName = {'Desired Joint Position (qf)'; 'Joint Variable'};
            app.qTable.RowName = {};
            app.DyPlotTabGroup = uitabgroup(app.DynamicsSimulationTab);
            app.JPosPlotTab = uitab(app.DyPlotTabGroup);
            app.JPosPlotTab.Title = 'Joint Position';
            app.JPosPlot = uiaxes(app.JPosPlotTab);
            title(app.JPosPlot, 'Joint Position')
            xlabel(app.JPosPlot, 'Time (sec)')
            ylabel(app.JPosPlot, 'Position (m or rad)')
            app.JVelPlotTab = uitab(app.DyPlotTabGroup);
            app.JVelPlotTab.Title = 'Joint Velocity';
            app.JVelPlot = uiaxes(app.JVelPlotTab);
            title(app.JVelPlot, 'Joint Velocity')
            xlabel(app.JVelPlot, 'Time (sec)')
            ylabel(app.JVelPlot, 'Velocity (m/s or rad/s)')
            app.TorqueTab = uitab(app.DyPlotTabGroup);
            app.TorqueTab.Title = 'Torque';
            app.TorqPlot = uiaxes(app.TorqueTab);
            title(app.TorqPlot, 'Joint Torque')
            xlabel(app.TorqPlot, 'Time (sec)')
            ylabel(app.TorqPlot, 'Tau')
            app.FrictionDropDownLabel = uilabel(app.DynamicsSimulationTab);
            app.FrictionDropDownLabel.Text = 'Friction?';
            app.FrictionDropDown = uidropdown(app.DynamicsSimulationTab);
            app.FrictionDropDown.Items = {'No', 'Yes'};
            app.FrictionDropDown.Value = 'No';
            app.SymbolicDynamicsEquationTab = uitab(app.TabGroup);
            app.SymbolicDynamicsEquationTab.Title = 'Symbolic Dynamics Equation';
            app.FormulateSymbolicEquationsofMotionButton = uibutton(app.SymbolicDynamicsEquationTab, 'push');
            app.FormulateSymbolicEquationsofMotionButton.ButtonPushedFcn = createCallbackFcn(app, @SymEoMButtonPushed, true);
            app.FormulateSymbolicEquationsofMotionButton.Text = 'Formulate Symbolic Equations of Motion ';
            app.DySymDisplay = uitextarea(app.SymbolicDynamicsEquationTab);
            app.InstructionLabel1_2 = uilabel(app.SymbolicDynamicsEquationTab);
            app.InstructionLabel1_2.Text = {'Choose the axis that gravity points down for the model. Then press the button and the symbolic'; ' form of the Equations of Motions for the Robotic arm with display in the text box below.'};
            app.AxisofGravityDropDownLabel = uilabel(app.SymbolicDynamicsEquationTab);
            app.AxisofGravityDropDownLabel.Text = 'Axis of Gravity';
            app.GravityDropDown = uidropdown(app.SymbolicDynamicsEquationTab);
            app.GravityDropDown.Items = {'Z', 'Y', 'X'};
            app.GravityDropDown.Value = 'Z';
            app.ControlsTab = uitab(app.MainTabGroup);
            app.ControlsTab.Title = 'Controls';
            app.RunControlsButton = uibutton(app.ControlsTab, 'push');
            app.RunControlsButton.ButtonPushedFcn = createCallbackFcn(app, @RunControlsButtonPushed, true);
            app.RunControlsButton.Text = 'Run Controls';
            app.SimulationTimesecEditFieldLabel_2 = uilabel(app.ControlsTab);
            app.SimulationTimesecEditFieldLabel_2.Text = 'Simulation Time (sec)';
            app.SimTime = uieditfield(app.ControlsTab, 'numeric');
            app.SimTime.Value = 3;
            app.ControlsInputTable = uitable(app.ControlsTab);
            app.ControlsInputTable.ColumnName = {'Initial End Effector Position (x0)'; 'Desired End Effector Position'};
            app.ControlsInputTable.RowName = {'x'; 'y'; 'z'};
            app.SimulationTimeStepLabel = uilabel(app.ControlsTab);
            app.SimulationTimeStepLabel.Text = 'Simulation Time Step';
            app.SimTimeStep = uieditfield(app.ControlsTab, 'numeric');
            app.SimTimeStep.Value = 0.01;
            app.GainTable = uitable(app.ControlsTab);
            app.GainTable.ColumnName = {'Kp'; 'Kd'};
            app.GainTable.RowName = {};
            app.SimulationControlAlgorithmSwitchLabel = uilabel(app.ControlsTab);
            app.SimulationControlAlgorithmSwitchLabel.Text = 'Simulation Control Algorithm';
            app.ControllerSwitch = uiswitch(app.ControlsTab, 'slider');
            app.ControllerSwitch.Items = {'PD wth Gravity Comp', 'Inverse Jacobian'};
            app.ControllerSwitch.Value = 'PD wth Gravity Comp';
            app.ControlPlot = uiaxes(app.ControlsTab);
            title(app.ControlPlot, 'End Effector Position')
            xlabel(app.ControlPlot, 'Time')
            ylabel(app.ControlPlot, 'Position')
            app.SerialRoboticArmSimulationToolLabel = uilabel(app.RobotSimTool_G10);
            app.SerialRoboticArmSimulationToolLabel.Text = 'Serial Robotic Arm Simulation Tool';
            % Show the figure after all components are created
            app.RobotSimTool_G10.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAE457_Project_G10_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotSimTool_G10)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotSimTool_G10)
        end
    end
end